classdef ClarosWeaver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ExerciseTypeDropDown       matlab.ui.control.DropDown
        ExerciseTypeDropDownLabel  matlab.ui.control.Label
        WhatMuscleGroupsWouldYouLiketoTargetLabel  matlab.ui.control.Label
        CreateMyScheduleButton     matlab.ui.control.Button
        NeedMoreInfoDropDown       matlab.ui.control.DropDown
        NeedMoreInfoLabel          matlab.ui.control.Label
        LegsCheckBox               matlab.ui.control.CheckBox
        LowerBodyCheckBox          matlab.ui.control.CheckBox
        CoreCheckBox               matlab.ui.control.CheckBox
        RearUpperBodyCheckBox      matlab.ui.control.CheckBox
        UpperFrontBodyCheckBox     matlab.ui.control.CheckBox
        UIAxes                     matlab.ui.control.UIAxes
    end

    %Gianna Claros and Vivianna Weaver

    properties (Access = private)
        FrontUpperBody;%muscle group photo
        RearUpperBody;%muscle group photo
        Core;%muscle group photo
        LowerBody;%muscle group photo
        Legs;%muscle group photo
        Image;%image for dropdown
        fig;%ui figure created by create my schedule button
        notChosen; %array that holds all checked boxes
        exclist;%list of exercises corresponding to muscle group boxes checked
        bg;%button group of schedule made of buttons
        weight;%weight # text box that changes by button press
        sets;%set # text box that changes by button press
        txt;%exercise description text box that changes by button press
        reps;%rep # text box that changes by button press
        drop;%
        newLink;
        picbutton;
        clicked;
    end

    methods (Access = public)

        function picchange(app, ~)%displays photo of exercise clicked
            if isequal(app.clicked.Text, "Arnold Press")
                imshow("arnold press.png");
            elseif isequal(app.clicked.Text,"Lat Raises")
                imshow("lat raises.png");
            elseif isequal(app.clicked.Text,"Around The Worlds")
                imshow("around the world.png");
            elseif isequal(app.clicked.Text,"Chest Fly")
                imshow("chest fly.png");
            elseif isequal(app.clicked.Text,"Tricep Extensions - Behind Head")
                imshow("tricep extensions behind head.png");
            elseif isequal(app.clicked.Text,"Situps")
                imshow("situps.png");
            elseif isequal(app.clicked.Text,"Crunches")
                imshow("situps.png");
            elseif isequal(app.clicked.Text,"Planks")
                imshow("planks.png");
            elseif isequal(app.clicked.Text,"Plank Up-downs")
                imshow("planks.png");
            elseif isequal(app.clicked.Text,"Mountain Climbers")
                imshow("mountain climbers.png");
            elseif isequal(app.clicked.Text,"Flutter Kicks")
                imshow("flutter kicks.png");
            elseif isequal(app.clicked.Text,"Russian Twists w/ Ball")
                imshow("russian twists.png");
            elseif isequal(app.clicked.Text,"Bent Over Barbell Row")
                imshow("bent barbell.png");
            elseif isequal(app.clicked.Text,"Seated Cable Row")
                imshow("seated cable row.png");
            elseif isequal(app.clicked.Text,"Lat Pull-Downs")
                imshow("lat pull downs.png");
            elseif isequal(app.clicked.Text,"Bicep Curls")
                imshow("bicep curls.png");
            elseif isequal(app.clicked.Text,"Pushups")
                imshow("pushups.png");
            elseif isequal(app.clicked.Text,"Barbell Curls")
                imshow("barbell curls.png");
            elseif isequal(app.clicked.Text,"Hammer Curls")
                imshow("hammer curls.png");
            elseif isequal(app.clicked.Text,"Leg Press")
                imshow("leg press.png");
            elseif isequal(app.clicked.Text,"V Squat")
                imshow("v squat.png");
            elseif isequal(app.clicked.Text,"Bulgarian split squats")
                imshow("bulgarian split squats.png");
            elseif isequal(app.clicked.Text,"RDLs")
                imshow("rdls.png");
            elseif isequal(app.clicked.Text,"Staggered RDLs")
                imshow("staggered rdls.png");
            elseif isequal(app.clicked.Text,"Split Squats")
                imshow("split squats.png");
            elseif isequal(app.clicked.Text,"Hip Abductions")
                imshow("hip abductions.png");
            elseif isequal(app.clicked.Text,"Elevated Step Ups")
                imshow("elevated step ups.png");
            elseif isequal(app.clicked.Text,"Barbell Squats")
                imshow("barbell squat.png");
            elseif isequal(app.clicked.Text,"Calf Raises")
                imshow("calf raises.png");
            elseif isequal(app.clicked.Text,"Steps Ups")
                imshow("elevated step ups.png");
            elseif isequal(app.clicked.Text,"Goblet Squats")
                imshow("goblet squats.png");
            elseif isequal(app.clicked.Text,"Elevated Goblet Squats")
                imshow("goblet squats.png");
            elseif isequal(app.clicked.Text,"Hamstring Curls")
                imshow("hamstring curl.png");
            elseif isequal(app.clicked.Text,"Hamstring Extension")
                imshow("hamstring extension.png");
            elseif isequal(app.clicked.Text,"Swimming")
                imshow("swimming.jpg");
            elseif isequal(app.clicked.Text,"Stairmaster")
                imshow("stairmaster.jpg");
            elseif isequal(app.clicked.Text,"Stretches")
                imshow("stretches.jpg");
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %loads in photos
            app.FrontUpperBody = imread("cpsupperbody.png");
            app.Legs = imread("cpslegs.png");
            app.RearUpperBody = imread("cpsrearupper.png");
            app.LowerBody = imread("cpslowerbody.png");
            app.Core = imread("cpscore.jpeg");
            %arrays to hold exercises
            app.exclist = [];
            app.notChosen = [];
            axis(app.UIAxes);
        end

        % Clicked callback: NeedMoreInfoDropDown
        function NeedMoreInfoDropDownClicked(app, event)
            %makes image show of muscle group chosen in drop down
            choice = app.NeedMoreInfoDropDown.Value;%gets selection
            switch choice%selects image to show
                case 'please select an option'
                case 'Legs'
                    app.Image = app.Legs;
                case 'Lower Body'
                    app.Image = app.LowerBody;
                case 'Core'
                    app.Image = app.Core;
                case 'Rear Upper Body'
                    app.Image = app.RearUpperBody;
                case 'Upper Front Body'
                    app.Image = app.FrontUpperBody;
            end
            imshow(app.Image,[],'Parent',app.UIAxes);%displays image
        end

        % Button pushed function: CreateMyScheduleButton
        function CreateMyScheduleButtonPushed(app, event)
            %creates schedule interface
            
            %makes an array of exercises that focus on the selected muscle
            %group and array of non chosen exercises to put
            %through out the schedule
            if app.UpperFrontBodyCheckBox.Value == 1
                app.exclist = [app.exclist,"Arnold Press","Lat Raises", "Around the Worlds", "Chest Fly","Tricep Extensions - Behind Head"];
            else
                app.notChosen = [app.notChosen,"Arnold Press","Lat Raises", "Around the Worlds", "Chest Fly","Tricep Extensions - Behind Head"];
            end
            if app.LegsCheckBox.Value == 1
                app.exclist = [app.exclist,"Leg Press","Calf Raises", "Step Up to Knee Dive", "Goblet Squats", "Elevated Goblet Squats","Hamstring Curl", "Hamstring Extension"];
            else
                app.notChosen = [app.notChosen,"Leg Press","Calf Raises", "Step Up to Knee Dive", "Goblet Squats", "Elevated Goblet Squats","Hamstring Curl", "Hamstring Extension"];
            end
            if app.CoreCheckBox.Value == 1
                app.exclist= [app.exclist,"Situps", "Crunches", "Planks","Plank Up Downs","Mountain Climbers","Flutter Kicks","Russian Twists w/ Ball"];
            else
                app.notChosen = [app.notChosen, "Situps", "Crunches","Planks", "Plank Up Downs","Mountain Climbers","Flutter Kicks","Russian Twists w/ Ball"];
            end
            if app.RearUpperBodyCheckBox.Value == 1
                app.exclist = [app.exclist, "Bent Over Barbell Row", "Seated Cable","Lat Pull-Downs","Bicep Curls","Pushups","Barbell Curls","Hammer Curls","Pull-ups"];
            else
                app.notChosen=[app.notChosen, "Bent Over Barbell Row", "Seated Cable Row","Lat Pull-Downs","Bicep Curls","Pushups","Barbell Curls","Hammer Curls","Pull-ups"];
            end
            if app.LowerBodyCheckBox.Value == 1
                app.exclist = [app.exclist, "Leg Press","V Squat","Split Squats","RDLs","Staggered RDLs","Bulgarian Split Squats","Hip Abductions","Elevated Step Ups","Barbell Squats"];
            else
                app.notChosen = [app.notChosen,"Leg Press","V Squat","Split Squats","RDLs","Staggered RDLs","Bulgarian Split Squats","Hip Abductions","Elevated Step Ups","Barbell Squats"];
            end

            fig = uifigure("Name",'Schedule','Color','0.15,0.15,0.15');%creates figure schedule is in
            bg = uibuttongroup(fig,"Position",[90 120 422 280], "SelectionChangedFcn", @(src,event)UIFigureButtonDown(app,event));%creates a button group to make interactive schedule
            txt = uitextarea(fig,"Position",[90 20 422 80],"Value","Excercise Description Here!");%text box for exercise descriptions
            app.picbutton = uibutton(fig,"Position",[20 25 65 50],"Text","Show me a photo!","WordWrap","on","ButtonPushedFcn",@(src,event)picchange(app,event));%when clicked displays photo of exercise
            sets = uitextarea(fig,"Position",[0 270 75 30],"Value","Sets");%display # of sets for exercise
            reps = uitextarea(fig,"Position",[0 240 75 30],"Value","Reps");%display # of reps for exercise
            weight = uitextarea(fig,"Position",[0 210 75 30],"Value","Weight");%display weight for exercise
            app.newLink = uihyperlink(fig,"Position",[100 80 70 20],'Text',"");%link for flexibility video
            %make global here bc fig shows only AFTER create button is
            %pushed and ui objects are made
            app.fig = fig;%makes variable public
            app.weight = weight;%makes variable public
            app.sets = sets;%makes variable public
            app.txt = txt;%makes variable public
            app.reps = reps;%makes variable public

            %adds/removes exercises based on exercise type
            switch app.ExerciseTypeDropDown.Value
                case 'Muscle Tone'
                    app.exclist = [app.exclist,'Swimming','Stairmaster'];%adds more difficult exercises
                case 'Beginner'
                    for i = 1:length(app.exclist)
                        if isequal(app.exclist{i},"Arnold Press") || isequal(app.exclist{i},"Barbell Squat") || ...
                                isequal(app.exclist{i},"Step up to Knee Dive") || isequal(app.exclist{i},"Elevated Goblet Squat") ...
                                || isequal(app.exclist{i},"Seated Cable Row") || isequal(app.exclist{i},"Bent Over Barbell Row")
                            i = i-1;%removes exercises from list and replace with the proper execise for type
                        end
                    end
                case 'Muscle Recovery'
                    for i = 1:length(app.exclist)
                        if isequal(app.exclist{i},"Arnold Press") || isequal(app.exclist{i},"Barbell Squat") || ...
                                isequal(app.exclist{i},"Step up to Knee Dive") || isequal(app.exclist{i},"Elevated Goblet Squat")|| ...
                                isequal(app.exclist{i},"Seated Cable Row") || isequal(app.exclist{i},"Bent Over Barbell Row")
                            app.exclist{i}=app.exclist{randi(length(app.exclist))};
                            i = i - 1;%removes exercises from list and replace with the proper execise for type
                        end
                    end
            end
            weekday = {'Sun','Mon','Tues','Weds','Thurs','Fri','Sat'};%titles for schedule

            left = 0;%part of placement for buttons in bg
            bottom = 246;%part of placement for buttons in bg
            for j = 1:7%rows
                for i = 1:6%columns
                    if i == 1%places weekday title for column
                        uitogglebutton(bg,"Text",weekday{j},"Position",[left bottom 62.5 34],"BackgroundColor",'#97c2cf','FontColor','#0e1a30',"FontSize", 12,'WordWrap','on');%title button
                        bottom = bottom - 49;%moves position for next button placement
                    elseif isequal(app.ExerciseTypeDropDown.Value,'Flexibility')&& j ~= 1%makes first exercise stretching 
                        if i == 2 %makes first exercise stretching for specific muscle group
                            uitogglebutton(bg,"Text","Stretches", "Position",[left bottom 62.5 49.5],'BackgroundColor',"#02153d",'FontColor','#f7f7f7',"FontSize", 9.5,'FontWeight','bold','WordWrap','on');%makes button
                        else
                            %if not flexibility chosen then replace w/ exercise from chosen list
                            uitogglebutton(bg,"Text",app.exclist(randi(length(app.exclist))),"Position",[left bottom 62.5 49.5],'BackgroundColor',"#02153d",'FontColor','#f7f7f7',"FontSize", 9.5,'FontWeight','bold','WordWrap','on');
                        end
                        bottom = bottom - 49;%moves next button placement to make next row
                    else
                        if j == 1
                            uitogglebutton(bg,"Text", "Rest Day","Position",[left bottom 62.5 49.5],"FontSize", 9.5,'BackgroundColor',"#02153d",'FontColor','#f7f7f7','FontWeight','bold','WordWrap','on');%makes sunday rest day
                            bottom = bottom - 49;%moves next button placement to make next row
                        elseif mod(i,2) == 0
                            uitogglebutton(bg,"Text",app.exclist(randi(length(app.exclist))),"Position",[left bottom 62.5 49.5],'BackgroundColor',"#02153d",'FontColor','#f7f7f7',"FontSize", 9.5,'FontWeight','bold','WordWrap','on');%adds in the chosen exercise
                            bottom = bottom - 49;%moves next button placement to make next row
                        else
                            uitogglebutton(bg,"Text",app.notChosen(randi(length(app.notChosen))),"Position",[left bottom 62.5 49.5],"FontSize", 9.5,'FontWeight','bold','FontColor','#f7f7f7','BackgroundColor',"#02153d",'WordWrap','on');%adds in non chosen exercises
                            bottom = bottom - 49;%moves next button placement to make next row
                        end
                    end
                end
                left = left+60;%moves to next column
                bottom = 246;%resets to top row
            end
            app.bg = bg;%makes public variable

            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            %makes buttons interactive by changing ui object based on
            %button clicked

            flex = [];%array to hold youtube videos for muscle group flexibility
            %puts specific videos in array by muscle group
            if app.UpperFrontBodyCheckBox.Value == 1
                flex= ["https://youtu.be/43NWZnVNAvc","https://www.youtube.com/watch?v=y2Zk8dA5KNs&pp=ygUddXBwZXIgYm9keSB3b3Jrb3V0IHN0cmV0Y2hpbmc%3D",];
            elseif app.RearUpperBodyCheckBox.Value == 1
                flex = ["https://youtu.be/dFeX4l13wh0","https://youtu.be/43NWZnVNAvc"];
            elseif app.CoreCheckBox.Value == 1
                flex = ["https://youtu.be/X_zuWITrq88 ","https://youtu.be/Ww7PPdH7Ulg"];
            elseif app.LowerBodyCheckBox.Value == 1
                flex = ["https://youtu.be/gqrv3uBgcGM","https://youtu.be/LstV8oveFJ0"];
            elseif app.LegsCheckBox.Value == 1
                flex = ["https://youtu.be/ximZfmqh2hI","https://youtu.be/LstV8oveFJ0"];
            end
                
            app.clicked = app.bg.SelectedObject;%holds object clicked
            
            %depending on object text will display & determine set and rep
            %values along w/ description
            if isequal(app.clicked.Text,"Arnold Press")
                app.txt.Value ="To do an Arnold press, hold a dumbbell in each hand.  Raise your hands in front of your chest with palms turned inward.  Raise the dumbbells above your head while turning your palms outward. Pause briefly at the top, then reverse this motion as you lower the dumbbells back to the front of your chest.  Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value = 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Lat Raises")
                app.txt.Value ="To do a lateral raise, hold a weight in each hand with palms facing inward.  Raise arms laterally until they are at shoulder level.  Lower the weights slowly until they nearly touch in front of your body.  A slight bend in the knees and waist may make this exercise slightly easier, but be careful to not bend too much-this may be an indication that the weights you;ve chosen are too heavy!";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 5';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Around the Worlds")
                app.txt.Value ="To do the around-the-world exercise, begin with a weight in each hand and your palms facing inwards.  Raise arms laterally to shoulder level with palms facing the ground.  Continue raising the arms laterally until they are above your head, with your arms straight and palms outward.  Reverse this motion until your arms are where they began.  Repeat!";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 5';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Chest Fly")
                app.txt.Value ="To perform a chest fly, hold a dumbbell in each hand.  Lie down on a flat bench with arms extended straight above your chest and palms facing inwards. Lower the arms laterally while keeping a slight bend in both arms.  When arms are level with your shoulders, pause briefly, then raise your arms to their starting positions.  Repeat!";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 5';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Tricep Extensions - Behind Head")
                app.txt.Value ="To do a tricep extension, hold a dumbbell in both hands.  Hold onto one side of it and raise it above your head.  Slowly lower the dumbbell several inches by bending the arms, then straighten your arms to raise the dumbbell back up.  Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 20';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 20';
                elseif isequal(app.ExerciseTypeDropDown.Value,"Muscle Recovery")
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Situps")
                app.txt.Value ="To do a situp, lay on your back with your knees bent and feet flat on the floor.  Place your arms across your chest and sit up without moving your legs and arms.  Slowly lay back down and repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Crunches")
                app.txt.Value ="To perform crunches, lay on your back with your knees bent and feet flat on the floor.  Place your arms across your chest and sit up partially without moving your legs and arms.  To know how much to sit up, sit up until you feel significant resistance in your core. Once you have crunched, lay back down and repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weights: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Planks")
                app.txt.Value ="To do a plank, lay parallel to the ground.  You can have your arms straight, or bent with elbows resting on the floor.  Your elbows should remain in line with your shoulders.  Hold the plank position for the designated amount of time.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Plank Up-downs")
                app.txt.Value ="To do a high plank, lay parallel to the ground.  Keep your arms straight.  Your elbows should remain in line with your shoulders. Once the plank position has been achieved, lower yourself one arm at a time until you are resting on your elbows.  Once you are in a lower plank, raise yourself one arm at a time until your arms are both straight again. Alternate between a high and low plank until the desired number of reps has been completed.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Mountain Climbers")
                app.txt.Value ="To do a high plank, lay parallel to the ground.  Keep your arms straight.  Your elbows should remain in line with your shoulders. Once in the plank position, bring your left knee to your right elbow and then return to the plank position. Next, bring your right knee to your left elbow and then return to the plank position.  Repeat this motion and gradually increase speed until the desired number of reps has been accomplished.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Flutter Kicks")
                app.txt.Value ="To perform a flutter kick, lay on your back with your hands flat by your sides and your legs straight and pointed.  Sit up slightly and raise your legs several inches off the ground. Alternate your legs in a small swimming kick for the designated amount of time.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Russian Twists w/ Ball")
                app.txt.Value ="To do a Russian twist, sit on the ground with your knees bent in front of you. Lean back slightly and raise your feet slightly off the ground.  Turn your upper body fully to each side for the designated time.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.rep.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Bent Over Barbell Row")
                app.txt.Value ="To perform this exercise, place your barbell in front of you.  Use an underhand grip to hold the barbell, with your hands placed about shoulder width apart. Stand until your back is at an angle slightly greater than 90 degrees and your arms are straight. Bring the barbell towards you in a pulling motion until it almost touches your chest.  Lower the barbell in a controlled fashion until your arms are straight again. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 30';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Seated Cable Row")
                app.txt.Value ="To perform seated cable rows, you will first need to find the appropriate machine. Sit on the provided bench and set the weight to your desired level.  Use both hands to grip the handles and pull, bringing your elbows back to your waist while leaning back slightly. Slowly release the weight, lengthening your arms and leaning forward slightly. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 40';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Lat Pull-Downs")
                app.txt.Value ="To perform lat pull-downs, you will first need to find the appropriate machine.  First, set the seat to the desired setting based on your height. Set the weight to your desired level.  Grab the bar with a wide grip and pull it down until you are sitting with your arms extended. Lean back with a slight arch in your back and pull the bar down to your chin. Slowly release until your arms are extended again. Repeat.  When you have completed your desired number of repetitions, stand up and place the bar back into its starting position.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 40';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 25';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 25';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Bicep Curls")
                app.txt.Value ="To perform bicep curls, hold a dumbbell of desired weight in each hand with palms facing outward. Curl the dumbbells up until they almost meet your bicep (upper arm). Curl them back down to their resting position. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Pushups")
                app.txt.Value ="To perform a pushup, first, move into the high plank position. To do a high plank, lay parallel to the ground.  Keep your arms straight.  Your elbows should remain in line with your shoulders. Once you are in this position, lower yourself most of the way to the ground.  Keep your elbows close to your body and move in a slow, controlled fashion. Pause very briefly at the bottom of your movement, then raise yourself back up to a high plank. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Barbell Curls")
                app.txt.Value ="To perform barbell curls, select a barbell of desired weight. Hold it in front of you with your arms straightened and hands placed approximately shoulder length apart. Your palms should face outwards. Curl the barbell towards you until your hands almost touch your bicep (upper arm). Curl it back down to their resting position. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 25';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 20';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Hammer Curls")
                app.txt.Value ="First select dumbbells of desired weight.  Hold one in each hand with your palms facing each other. Your arms should be lengthened so one side of each dumbbell nearly rests on your upper thighs. Keeping your arms parallel, bend your elbows so they form a 90 degree angle.  Pause briefly then straighten your arms. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Pull-ups")
                app.txt.Value ="To perform pull-ups, you should first find a pull-up bar. Place your hands in a comfortable configuration that works for you, then raise yourself up to the bar until your chin touches it. Slowly lower yourself until your arms are mostly straightened, then repeat. Do not straighten your arms all the way.  If you do this, the remaining repetitions will become much more difficult.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 5';
                    app.sets.Value='Sets: 4';
                    app.weight.Value = 'Weight: N/A';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Leg Press")
                app.txt.Value ="First, find the leg press machine. Use the weights racked on the side of the machine to load the machine with the desired weight. Sit in the seat and place your feet on the sled above you in whatever feels like a safe and comfortable position. Unrack the sled by using the handles to your sides. Turn them to the side and the sled will move. Once the weights are unracked, move the weight by bending your knees and moving the weight toward you in an almost reverse squat. Remember to move slowly and controlled, straighten your legs but do not lock the knees. Repeat. Once you have done your desired number of repetitions, rerack the sled by moving the handles at your sides back toward you.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 2x45';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"V Squat")
                app.txt.Value ="To perform V-squats, first find the squat machine. Use the weights racked on the side of the machine to load the machine with the desired weight. Climb onto the platform and stand so your shoulders are directly under the shoulder pads. Unrack the weights by simply standing up straight. Perform a squat movement, remembering to keep your back straight against the back of the machine to prevent back pain. Repeat the movement for the desired number of reps. Once complete, use the handle at the side of the machine to rerack the weights. Pull the handle up and slowly lower yourself until you feel the machine rerack.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 2x45';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Bulgarian split squats")
                app.txt.Value ="To perform Bulgarian split squats, find an available bench. Hold the desired weight if you wish to hold one, and sit on the side of the bench with one foot underneath you. Extend the other leg until it is straightened, then lean forward on that leg until you are standing with both legs bent in a lunge-like formation, with the back leg resting on the bench. Lower yourself until your knee is close to the ground, then raise yourself back up. Make sure to move slowly with control. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value= 'Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"RDLs")
                app.txt.Value ="To perform RDLVs, first select dumbbells of the desired weight. Stand straight up with your dumbbells in front of you and your palms turned inward. Bend forward while pushing your hips back and lowering your hands. It may help to imagine yourself beginning to sit into a chair. Keep your hands close to your legs as you move. Stand up slowly while keeping the dumbbells close to you. Make sure to keep your back straight. Repeat movement.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value='Weight: 30';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Staggered RDLs")
                app.txt.Value ="To perform RDLVs, first select dumbbells of the desired weight. Stand straight up with your dumbbells in front of you and your palms turned inward. Bend forward while pushing your hips back and lowering your hands. It may help to imagine yourself beginning to sit in a chair. Keep your hands close to your legs as you move. Stand up slowly while keeping the dumbbells close to you. Make sure to keep your back straight. To modify this movement to make your RDL’s staggered, simply drop one dumbbell and place the leg on the side of the remaining dumbbell slightly forward. Continue the movement in this new position.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value='Weight: 30';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Split Squats")
                app.txt.Value ="To perform split squats, stand and then move one leg out into a lunge-like formation. Lower yourself as if you are performing a solitary lunge then raise yourself back up. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 30';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Hip Abductions")
                app.txt.Value ="First, find the appropriate hip abduction machine. Set the weight to the desired level and use the lever on the side of the machine to set the pads to the desired width. Move the pads out by engaging your hips. Slowly release and allow the pads to move back together. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 50';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 10';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Elevated Step Ups")
                app.txt.Value ="First, find an available elevated surface of approximately your knee height. Pick one dumbbell of desired weight and hold it in one hand. Place the corresponding foot on the surface and slowly step up until you are standing straight on the elevated surface. Step back down on the same foot. Repeat and then switch feet.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Barbell Squat")
                app.txt.Value ="To do a barbell squat, place the barbell of desired weight on your back between your shoulder blades. Perform a squat motion by slowly lowering yourself and bend your knees.  Stand and repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value='Weight: 2x45';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Calf Raises")
                app.txt.Value ="To perform calf raises, stand straight with a dumbbell of desired weight in each hand. Rise onto your toes, pause briefly, and lower yourself slowly. Do your desired number of repetitions, and on the last one, hold the movement at the top for as many seconds as you can. Repeat.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 30';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Goblet Squats")
                app.txt.Value ="To perform goblet squats, choose one dumbbell of your desired weight and hold it in both hands in front of your chest. Stand with your feet slightly apart and knees turned slightly outwards. Bend at the knees until you are in a full squat then stand back up.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 30';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 0';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Elevated Goblet Squats")
                app.txt.Value ="To perform goblet squats, choose one dumbbell of your desired weight and hold it in both hands in front of your chest. Stand with your feet slightly apart and knees turned slightly outwards. Bend at the knees until you are in a full squat then stand back up. To perform these with the elevated modification, first, place a 25-45 pound plate on the ground, then place your heels on it. Perform your repetitions in this position. This allows for a stronger focus on the quad muscles.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 30';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Hamstring Curl")
                app.txt.Value ="First, find the appropriate machine for hamstring curls. Use the levels located behind the seat and near the curling feature to move the machine to match your size.  Move into the seat then lower the pad above your knees until it rests directly on top of your legs. Set the machine to your desired weight then, bending your knees, move the curling pad down. Repeat this movement for the desired number of repetitions.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 40';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 25';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 25';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Hamstring Extension")
                app.txt.Value ="First, find the appropriate machine for hamstring extensions. Use the levels located behind the seat and by the extension feature to move the machine to match your size. Move into the seat and place your feet under the extending bar.  You may need to adjust the levers. The bar should rest on the front of your ankles. Set the machine to your desired weight. Straighten your legs until they are directly in front of you. Repeat this movement for the desired number of repetitions.";
                if isequal(app.ExerciseTypeDropDown.Value,'Flexibility')|| isequal(app.ExerciseTypeDropDown.Value,'Muscle Tone')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 40';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Beginner')
                    app.reps.Value= 'Reps: 8';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 15';
                elseif isequal(app.ExerciseTypeDropDown.Value,'Muscle Recovery')
                    app.reps.Value= 'Reps: 10';
                    app.sets.Value='Sets: 4';
                    app.weight.Value= 'Weight: 25';
                else
                    app.reps.Value = 'Reps: N/A';
                    app.sets.Value = 'Sets：N/A';
                    app.weight.Value = 'Weight: N/A';
                end
            elseif isequal(app.clicked.Text,"Swimming")
                app.txt.Value = "First, find the natatorium in your gym center, assuming one is available. Feel free to swim laps or float around as you feel you need. Swimming can be a wonderful cool-down from a workout!";
                app.reps.Value = 'Reps: N/A';
                app.sets.Value = 'Sets：N/A';
                app.weight.Value = 'Weight: N/A';
            elseif isequal(app.clicked.Text, "Stairmaster")
                app.txt.Value = "First, climb onto the machine. Set the speed for what you wish it to be and begin climbing!";
                app.reps.Value = 'Reps: N/A';
                app.sets.Value = 'Sets：N/A';
                app.weight.Value = 'Weight: N/A';
            end
            if isequal(app.clicked.Text,"Stretches")%link will only show up if stretches clicked
                app.txt.Value = "";%description of last exercise cleared
                app.newLink.Text = "Click Here";
                app.newLink.URL= flex(randi(length(flex)));%makes value of link a flexibility video
                app.reps.Value = 'Reps: N/A';
                app.sets.Value = 'Sets：N/A';
                app.weight.Value = 'Weight: N/A';
            else
                app.newLink.Text = "";%clears text
                app.newLink.URL= "";%clears url 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UpperFrontBodyCheckBox = uicheckbox(app.UIFigure);
            app.UpperFrontBodyCheckBox.Text = 'Upper Front Body';
            app.RearUpperBodyCheckBox = uicheckbox(app.UIFigure);
            app.RearUpperBodyCheckBox.Text = 'Rear Upper Body';
            app.CoreCheckBox = uicheckbox(app.UIFigure);
            app.CoreCheckBox.Text = 'Core';
            app.LowerBodyCheckBox = uicheckbox(app.UIFigure);
            app.LowerBodyCheckBox.Text = 'Lower Body';
            app.LegsCheckBox = uicheckbox(app.UIFigure);
            app.LegsCheckBox.Text = 'Legs';
            app.NeedMoreInfoLabel = uilabel(app.UIFigure);
            app.NeedMoreInfoLabel.Text = 'Need More Info?';
            app.NeedMoreInfoDropDown = uidropdown(app.UIFigure);
            app.NeedMoreInfoDropDown.Items = {'please select an option', 'Legs', 'Core', 'Lower Body', 'Rear Upper Body', 'Upper Front Body'};
            app.NeedMoreInfoDropDown.ClickedFcn = createCallbackFcn(app, @NeedMoreInfoDropDownClicked, true);
            app.NeedMoreInfoDropDown.Value = 'please select an option';
            app.CreateMyScheduleButton = uibutton(app.UIFigure, 'push');
            app.CreateMyScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMyScheduleButtonPushed, true);
            app.CreateMyScheduleButton.Text = 'Create My Schedule!';
            app.WhatMuscleGroupsWouldYouLiketoTargetLabel = uilabel(app.UIFigure);
            app.WhatMuscleGroupsWouldYouLiketoTargetLabel.Text = 'What Muscle Groups Would You Like to Target?';
            app.ExerciseTypeDropDownLabel = uilabel(app.UIFigure);
            app.ExerciseTypeDropDownLabel.Text = 'Exercise Type:';
            app.ExerciseTypeDropDown = uidropdown(app.UIFigure);
            app.ExerciseTypeDropDown.Items = {'Select Type', 'Flexibility', 'Muscle Recovery', 'Beginner', 'Muscle Tone'};
            app.ExerciseTypeDropDown.Value = 'Select Type';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClarosWeaver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end